classdef ACsN_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoadButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        NAEditFieldLabel            matlab.ui.control.Label
        NAEditField                 matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel  matlab.ui.control.Label
        WavelengthnmEditField       matlab.ui.control.NumericEditField
        PixelsizenmEditFieldLabel   matlab.ui.control.Label
        PixelsizenmEditField        matlab.ui.control.NumericEditField
        VideoFilterDropDownLabel    matlab.ui.control.Label
        VideoFilterDropDown         matlab.ui.control.DropDown
        OffsetEditFieldLabel        matlab.ui.control.Label
        OffsetEditField             matlab.ui.control.EditField
        GainEditFieldLabel          matlab.ui.control.Label
        GainEditField               matlab.ui.control.EditField
        ACsNLabel                   matlab.ui.control.Label
        CheckBox                    matlab.ui.control.CheckBox
        ParallelCPULabel            matlab.ui.control.Label
        OKButton                    matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        output = 0;
        input = 0;
        offset = 0;
        gain = 1;
        
    end
    

    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file, path] = uigetfile({'*.tif';'*.mat'},'Select an image');
            if file~=0
                if prod(file(end-3:end) == '.tif') ||  prod(file(end-4:end) == '.tiff')
                    app.input = double(loadtiff([path file]));
                elseif prod(file(end-3:end) == '.mat')
                    Struct = load([path file],'image');
                    app.input = Struct.image;
                end
                
                if size(app.input,3) == 1
                    figure;imagesc(app.input); axis off; axis image;
                else
                    implay(nrm(app.input));
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.output ~= 0
                acsn = app.output;
                uisave('acsn','acsn');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file, path] = uigetfile({'*.mat';'*.tif'},'Select the offset file');
            if file~=0
                if prod(file(end-3:end) == '.tif') ||  prod(file(end-4:end) == '.tiff')
                    app.offset = double(loadtiff([path file]));
                elseif prod(file(end-3:end) == '.mat')
                    Struct = load([path file],'offset');
                    app.offset = Struct.offset;
                end
                app.OffsetEditField.Value = [path file];
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file, path] = uigetfile({'*.mat';'*.tif'},'Select the offset file');
            if file~=0
                if prod(file(end-3:end) == '.tif') ||  prod(file(end-4:end) == '.tiff')
                    app.gain = double(loadtiff([path file]));
                elseif prod(file(end-3:end) == '.mat')
                    Struct = load([path file],'gain');
                    app.gain = Struct.gain;
                end
                app.GainEditField.Value = [path file];
            end
        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
            value = app.OffsetEditField.Value;
            if size(value,1) >= 5
                if prod(value(end-3:end) == '.tif') ||  prod(value(end-4:end) == '.tiff')
                    app.offset = double(loadtiff(value));
                elseif prod(value(end-3:end) == '.mat')
                    Struct = load(value,'gain');
                    app.gain = Struct.gain;
                end
            end
            
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
            if size(value,1) >= 5
                if prod(value(end-3:end) == '.tif') ||  prod(value(end-4:end) == '.tiff')
                    app.gain = double(loadtiff(value));
                elseif prod(value(end-3:end) == '.mat')
                    Struct = load(value,'gain');
                    app.gain = Struct.gain;
                end
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            NA = app.NAEditField.Value;
            Lambda = app.WavelengthnmEditField.Value.*1e-3;
            PxSize = app.PixelsizenmEditField.Value.*1e-3;
            VideoMode = app.VideoFilterDropDown.Value;
            ParallelMode = 'Normal';
            
            if app.CheckBox.Value == 1
                ParallelMode = 'Parallel';
            end
            
            app.output = ACSN(app.input,NA,Lambda,PxSize,'Gain',app.gain,'Offset',app.offset,'Mode',ParallelMode,'Video',VideoMode);
            
            if size(app.input,3) == 1
                figure;imagesc(app.output); axis off; axis image;
            else
                implay(nrm(app.output));
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NAEditFieldLabel = uilabel(app.UIFigure);
            app.NAEditFieldLabel.Text = 'NA';
            app.NAEditField = uieditfield(app.UIFigure, 'numeric');
            app.NAEditField.Limits = [0 Inf];
            app.WavelengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavelengthnmEditField.Limits = [0 Inf];
            app.PixelsizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsizenmEditFieldLabel.Text = 'Pixel size (nm)';
            app.PixelsizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.VideoFilterDropDownLabel = uilabel(app.UIFigure);
            app.VideoFilterDropDownLabel.Text = 'Video Filter';
            app.VideoFilterDropDown = uidropdown(app.UIFigure);
            app.VideoFilterDropDown.Items = {'auto', 'yes', 'no'};
            app.VideoFilterDropDown.Value = 'auto';
            app.OffsetEditFieldLabel = uilabel(app.UIFigure);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.UIFigure, 'text');
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'text');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.ACsNLabel = uilabel(app.UIFigure);
            app.ACsNLabel.Text = 'ACsN';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.ParallelCPULabel = uilabel(app.UIFigure);
            app.ParallelCPULabel.Text = 'Parallel CPU';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ACsN_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end